classdef moon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ControlsPanel             matlab.ui.container.Panel
        FilterSizeSpinner         matlab.ui.control.Spinner
        FilterSizeSpinnerLabel    matlab.ui.control.Label
        kSpinner                  matlab.ui.control.Spinner
        kSpinnerLabel             matlab.ui.control.Label
        FilterSizeEditField       matlab.ui.control.NumericEditField
        FilterSizeEditFieldLabel  matlab.ui.control.Label
        kEditField                matlab.ui.control.NumericEditField
        kEditFieldLabel           matlab.ui.control.Label
        SelectImageButton         matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            % Button pushed function: TakeanImageButton

            [filename, pathname] = uigetfile('*.*', 'Select Image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            imshow(a,'Parent',app.UIAxes);
            filter = ones(app.FilterSizeEditField.Value, app.FilterSizeEditField.Value) / (app.FilterSizeEditField.Value * app.FilterSizeEditField.Value);

% Load input image and convert to double
f = im2double(a);

% Perform convolution with filter
f_bar = conv2(f, filter, 'same');

% Calculate unsharp mask
g_mask = f - f_bar;

% Calculate sharpened image using highboost filtering
g = (app.kEditField.Value + 1) * f - app.kEditField.Value * f_bar;
imshow(g,'Parent',app.UIAxes2);
app.FilterSizeEditField.Editable=false;
app.kEditField.Editable=false;

        end

        % Value changing function: kSpinner
        function kSpinnerValueChanging(app, event)
            
    
    % Get the changing value from the spinner
    changingValue = event.Value;

    % Update the spinner value to the new value
    app.kSpinner.Value = changingValue;

    % Get the current image data from the UIAxes
    a = app.UIAxes.Children.CData;

    % Get the filter size from the FilterSizeSpinner
    filterSize = app.FilterSizeSpinner.Value;

    % Create a filter with the specified size
    filter = ones(filterSize) / (filterSize^2);

    % Convert the image to double precision
    f = im2double(a);

    % Convolve the image with the filter
    f_bar = conv2(f, filter, 'same');

    % Calculate the sharpened image using high-boost filtering
    k = changingValue;
    g = (k+1)*f - k*f_bar;

    % Display the sharpened image
    imshow(g, 'Parent', app.UIAxes2);


        end

        % Value changing function: FilterSizeSpinner
        function FilterSizeSpinnerValueChanging(app, event)
            
  
    % Get the changing value from the spinner
    changingValue = event.Value;

    % Update the spinner value to the new value
    app.FilterSizeSpinner.Value = changingValue;

    % Get the current image data from the UIAxes
    a = app.UIAxes.Children.CData;

    % Get the filter size from the spinner
    filterSize = changingValue;

    % Create a filter with the specified size
    filter = ones(filterSize) / (filterSize^2);

    % Convert the image to double precision
    f = im2double(a);

    % Convolve the image with the filter
    f_bar = conv2(f, filter, 'same');

    % Calculate the sharpened image using high-boost filtering
    k = app.kSpinner.Value;
    g = (k+1)*f - k*f_bar;

    % Display the sharpened image
    imshow(g, 'Parent', app.UIAxes2);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Output')
            app.UIAxes2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.SelectImageButton = uibutton(app.ControlsPanel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.kEditFieldLabel = uilabel(app.ControlsPanel);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.FilterSizeEditFieldLabel = uilabel(app.ControlsPanel);
            app.FilterSizeEditFieldLabel.Text = 'Filter Size';
            app.FilterSizeEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.kSpinnerLabel = uilabel(app.ControlsPanel);
            app.kSpinnerLabel.Text = 'k';
            app.kSpinner = uispinner(app.ControlsPanel);
            app.kSpinner.ValueChangingFcn = createCallbackFcn(app, @kSpinnerValueChanging, true);
            app.kSpinner.Limits = [-5 10];
            app.FilterSizeSpinnerLabel = uilabel(app.ControlsPanel);
            app.FilterSizeSpinnerLabel.Text = 'Filter Size';
            app.FilterSizeSpinner = uispinner(app.ControlsPanel);
            app.FilterSizeSpinner.ValueChangingFcn = createCallbackFcn(app, @FilterSizeSpinnerValueChanging, true);
            app.FilterSizeSpinner.Limits = [3 15];
            app.FilterSizeSpinner.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = moon

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end